classdef B50013_B56745_AppResina < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RegistrodeDatosTab             matlab.ui.container.Tab
        MtodosNumricosparalaLabel      matlab.ui.control.Label
        ConduccindeCalorLabel          matlab.ui.control.Label
        Descripcion                    matlab.ui.control.Label
        Densidadkgm3EditFieldLabel     matlab.ui.control.Label
        Densidad                       matlab.ui.control.NumericEditField
        CpJkgCLabel                    matlab.ui.control.Label
        Cp                             matlab.ui.control.NumericEditField
        kWmCEditFieldLabel             matlab.ui.control.Label
        Conducti                       matlab.ui.control.NumericEditField
        hWm2CEditFieldLabel            matlab.ui.control.Label
        CoefConv                       matlab.ui.control.NumericEditField
        LmEditFieldLabel               matlab.ui.control.Label
        Longitud                       matlab.ui.control.NumericEditField
        TiempoTranscurridominEditFieldLabel  matlab.ui.control.Label
        tiempoUsuario                  matlab.ui.control.NumericEditField
        TiCEditFieldLabel              matlab.ui.control.Label
        TempInicial                    matlab.ui.control.NumericEditField
        TooCEditFieldLabel             matlab.ui.control.Label
        TempFluid                      matlab.ui.control.NumericEditField
        MaterialEditFieldLabel         matlab.ui.control.Label
        Material                       matlab.ui.control.EditField
        FourierTab                     matlab.ui.container.Tab
        MtodosNumricosparalaLabel_2    matlab.ui.control.Label
        ConduccindeCalorLabel_2        matlab.ui.control.Label
        Descripcion2                   matlab.ui.control.Label
        IntervalodeTiempoMaximoAdmisiblesEditFieldLabel  matlab.ui.control.Label
        TiemMaxAdmi                    matlab.ui.control.NumericEditField
        CriteriodeEstabilidadTextAreaLabel  matlab.ui.control.Label
        CriteriodeEstabilidadTextArea  matlab.ui.control.TextArea
        SegnConvenienciaTextAreaLabel  matlab.ui.control.Label
        SegnConvenienciaTextArea       matlab.ui.control.TextArea
        IntervalodeTiempoporconvenienciasEditFieldLabel  matlab.ui.control.Label
        Tiemconveniencia               matlab.ui.control.NumericEditField
        NmerodeIteracionesEditFieldLabel  matlab.ui.control.Label
        NmerodeIteraciones             matlab.ui.control.NumericEditField
        CalcularButton                 matlab.ui.control.Button
        CumpleLampLabel                matlab.ui.control.Label
        CumpleLamp                     matlab.ui.control.Lamp
        TemperaturasTab                matlab.ui.container.Tab
        MtodosNumricosparalaLabel_3    matlab.ui.control.Label
        ConduccindeCalorLabel_3        matlab.ui.control.Label
        Descripcion3                   matlab.ui.control.Label
        LastemperaturasenlosnodosdespuesdeltiempotranscurridosonLabel  matlab.ui.control.Label
        Tnodo0CEditFieldLabel          matlab.ui.control.Label
        Tnodo0Muestra                  matlab.ui.control.NumericEditField
        Tnodo1CEditFieldLabel          matlab.ui.control.Label
        Tnodo1Muestra                  matlab.ui.control.NumericEditField
        Tnodo2CEditFieldLabel          matlab.ui.control.Label
        Tnodo2Muestra                  matlab.ui.control.NumericEditField
        MaterialEditField_2Label       matlab.ui.control.Label
        Material1                      matlab.ui.control.EditField
        TiempoTranscurridominEditField_2Label  matlab.ui.control.Label
        tiempoMuestra                  matlab.ui.control.NumericEditField
        VerTemperaturasButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        
        %parámetros del enunciado
        k; %Conductividad Termica
        p; %Densidad rho
        cp;%Calor Especifico
        L; %Espesor de la placa de resina
        h; %Coeficiente de Transferencia de Calor por Conv
        M=3; %Numero de Nodos
        tiempo; %tiempo de Analisis deseado (min)
        

        %Temperaturas
        Ti; %Temperatura Inicial
        Too; %Temperatura Fluido
        
        %Para Calculos Basicos
        alpha; %Difusividad Termica
        tiempoTotal; %Tiempo total en segundos
        deltaX; %Tamaño de DeltaX
        
        %Para Cumplir Criterio de Estabilidad
        deltaTMax; 
        DeltaTMaxMin; %Para mostrar en minutos
        %%%%El valor Maximo Admisible del intervalo de tiempo que cumple criterio de estabilidad
        
        nIteracionesMax; %Solicitado
        deltaT;%Para solicitar numero de iteraciones y
        %determinar deltaT de ahi. Verificar si cumple Criterio de Estabilidad
        Tau; %Tau con intervalo escogido.
        
        %Suposiciones
        T0;
        T1;
        T2;
        
        %Para iterar
        nIteraciones;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: tiempoMuestra
        function tiempoMuestraValueChanged(app, event)
            value = app.tiempoMuestra.Value;
            
        end

        % Value changed function: tiempoUsuario
        function tiempoUsuarioValueChanged(app, event)
        app.tiempo = app.tiempoUsuario.Value;
        app.tiempoMuestra.Value= app.tiempoUsuario.Value;
        end

        % Value changed function: Material1
        function Material1ValueChanged(app, event)
            value = app.Material1.Value;
            
        end

        % Value changed function: Material
        function MaterialValueChanged(app, event)
             app.Material1.Value=app.Material.Value;
            
        end

        % Value changed function: Densidad
        function DensidadValueChanged(app, event)
            value = app.Densidad.Value;
            app.p = app.Densidad.Value;
        end

        % Value changed function: Cp
        function CpValueChanged(app, event)
            value = app.Cp.Value;
            app.cp = app.Cp.Value;
        end

        % Value changed function: Conducti
        function ConductiValueChanged(app, event)
            value = app.Conducti.Value;
            app.k = app.Conducti.Value;
        end

        % Value changed function: Longitud
        function LongitudValueChanged(app, event)
            value = app.Longitud.Value;
            app.L = app.Longitud.Value;
        end

        % Value changed function: CoefConv
        function CoefConvValueChanged(app, event)
            value = app.CoefConv.Value;
            app.h = app.CoefConv.Value;
        end

        % Value changed function: TempInicial
        function TempInicialValueChanged(app, event)
            value = app.TempInicial.Value;
            app.Ti = app.TempInicial.Value;
        end

        % Value changed function: TempFluid
        function TempFluidValueChanged(app, event)
            value = app.TempFluid.Value;
            app.Too = app.TempFluid.Value;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            TiemMaxAdmiValueChanged(app, event)
        end

        % Value changed function: TiemMaxAdmi
        function TiemMaxAdmiValueChanged(app, event)
         app.alpha = (app.k)/(app.p * app.cp);
         app.tiempoTotal = app.tiempo * 60;
         app.deltaX = (app.L)/(app.M-1);
         
         app.deltaTMax=((app.deltaX)^2)/(2*app.alpha*((1)+(app.h*app.deltaX)/(app.k)));
         app.DeltaTMaxMin=app.deltaTMax*(1/60);
         
         app.TiemMaxAdmi.Value = app.deltaTMax;
            
        end

        % Value changed function: Tiemconveniencia
        function TiemconvenienciaValueChanged(app, event)
            app.deltaT=(app.tiempoTotal) / (app.nIteracionesMax);
            app.Tiemconveniencia.Value = app.deltaT;
            
            if (app.deltaT < app.deltaTMax)
                app.CumpleLamp.Color = [0.00 1.00 0.00];
                % Verde 0.00,1.00,0.00
            else
                app.CumpleLamp.Color = [0.94 0.94 0.94];
                % Gris 0.94,0.94,0.94
            end
            
        end

        % Value changed function: NmerodeIteraciones
        function NmerodeIteracionesValueChanged(app, event)
            value = app.NmerodeIteraciones.Value;
            app.nIteracionesMax = app.NmerodeIteraciones.Value;
            
            TiemconvenienciaValueChanged(app, event)
        end

        % Size changed function: FourierTab
        function FourierTabSizeChanged(app, event)
            position = app.FourierTab.Position;
        end

        % Value changed function: Tnodo0Muestra
        function Tnodo0MuestraValueChanged(app, event)
            value = app.Tnodo0Muestra.Value;
            app.Tnodo0Muestra.Value = app.T0;
        end

        % Value changed function: Tnodo1Muestra
        function Tnodo1MuestraValueChanged(app, event)
            app.Tnodo1Muestra.Value = app.T1;
        end

        % Value changed function: Tnodo2Muestra
        function Tnodo2MuestraValueChanged(app, event)
            app.Tnodo2Muestra.Value = app.T2;
            
        end

        % Button pushed function: VerTemperaturasButton
        function VerTemperaturasButtonPushed(app, event)
            syms Tnodo0i Tnodo1i Tnodo2i T0i T1i T2i nIteMax
            
            app.Tau= (app.alpha*app.deltaT)/((app.deltaX)^2); %Tau con intervalo escogido.
            
            %Suposiciones
            T0i=app.Ti;
            T1i=app.Ti;
            T2i=app.Ti;

            %Para iterar
            app.nIteraciones = 0;
            nIteMax = app.nIteracionesMax;
            
            %Calculos de Temperaturas
            for i=1:nIteMax 
                %Calculo de T0i+1
                Tnodo0i(i)= (1-(2*app.Tau)-(2*app.Tau*app.h*(app.deltaX/app.k)))*T0i + (2*app.Tau*T1i) + (2*app.Tau*(app.h*app.deltaX/app.k)*app.Too);
                %Calculo de T1 i+1
                Tnodo1i(i)= app.Tau*(T0i+T2i) + (1-(2*app.Tau))*T1i;
                %Calculo de T2i, se tiene que T1i=T2i
                Tnodo2i(i)= Tnodo1i(i);
           
                %Se tiene entonces
                T0i=Tnodo0i(i);
                T1i=Tnodo1i(i);
                T2i=Tnodo2i(i);
            end
            
            app.T0 = double(T0i);
            app.T1 = double(T1i);
            app.T2 = double(T2i);
            
            Tnodo0MuestraValueChanged(app, event)
            Tnodo1MuestraValueChanged(app, event)
            Tnodo2MuestraValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrodeDatosTab = uitab(app.TabGroup);
            app.RegistrodeDatosTab.Title = 'Registro de Datos';
            app.MtodosNumricosparalaLabel = uilabel(app.RegistrodeDatosTab);
            app.MtodosNumricosparalaLabel.Text = 'Métodos Numéricos para la';
            app.ConduccindeCalorLabel = uilabel(app.RegistrodeDatosTab);
            app.ConduccindeCalorLabel.Text = 'Conducción de Calor';
            app.Descripcion = uilabel(app.RegistrodeDatosTab);
            app.Descripcion.Text = '(Para una Placa 1D, asilada en la parte inferior y expuesta a convección)';
            app.Densidadkgm3EditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.Densidadkgm3EditFieldLabel.Text = 'Densidad (kg/m3)';
            app.Densidad = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.Densidad.ValueChangedFcn = createCallbackFcn(app, @DensidadValueChanged, true);
            app.CpJkgCLabel = uilabel(app.RegistrodeDatosTab);
            app.CpJkgCLabel.Text = 'Cp (J/kg°C)';
            app.Cp = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.Cp.ValueChangedFcn = createCallbackFcn(app, @CpValueChanged, true);
            app.kWmCEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.kWmCEditFieldLabel.Text = 'k (W/m °C)';
            app.Conducti = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.Conducti.ValueChangedFcn = createCallbackFcn(app, @ConductiValueChanged, true);
            app.hWm2CEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.hWm2CEditFieldLabel.Text = 'h (W/m2°C)';
            app.CoefConv = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.CoefConv.ValueChangedFcn = createCallbackFcn(app, @CoefConvValueChanged, true);
            app.LmEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.LmEditFieldLabel.Text = 'L (m)';
            app.Longitud = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.Longitud.ValueChangedFcn = createCallbackFcn(app, @LongitudValueChanged, true);
            app.TiempoTranscurridominEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.TiempoTranscurridominEditFieldLabel.Text = 'Tiempo Transcurrido (min)';
            app.tiempoUsuario = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.tiempoUsuario.ValueChangedFcn = createCallbackFcn(app, @tiempoUsuarioValueChanged, true);
            app.TiCEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.TiCEditFieldLabel.Text = 'Ti (°C)';
            app.TempInicial = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.TempInicial.ValueChangedFcn = createCallbackFcn(app, @TempInicialValueChanged, true);
            app.TooCEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.TooCEditFieldLabel.Text = 'Too (°C)';
            app.TempFluid = uieditfield(app.RegistrodeDatosTab, 'numeric');
            app.TempFluid.ValueChangedFcn = createCallbackFcn(app, @TempFluidValueChanged, true);
            app.MaterialEditFieldLabel = uilabel(app.RegistrodeDatosTab);
            app.MaterialEditFieldLabel.Text = 'Material';
            app.Material = uieditfield(app.RegistrodeDatosTab, 'text');
            app.Material.ValueChangedFcn = createCallbackFcn(app, @MaterialValueChanged, true);
            app.Material.Value = '-';
            app.FourierTab = uitab(app.TabGroup);
            app.FourierTab.SizeChangedFcn = createCallbackFcn(app, @FourierTabSizeChanged, true);
            app.FourierTab.Title = 'Fourier';
            app.MtodosNumricosparalaLabel_2 = uilabel(app.FourierTab);
            app.MtodosNumricosparalaLabel_2.Text = 'Métodos Numéricos para la';
            app.ConduccindeCalorLabel_2 = uilabel(app.FourierTab);
            app.ConduccindeCalorLabel_2.Text = 'Conducción de Calor';
            app.Descripcion2 = uilabel(app.FourierTab);
            app.Descripcion2.Text = '(Para una Placa 1D, aislada en la parte inferior y expuesta a convección)';
            app.IntervalodeTiempoMaximoAdmisiblesEditFieldLabel = uilabel(app.FourierTab);
            app.IntervalodeTiempoMaximoAdmisiblesEditFieldLabel.Text = 'Intervalo de Tiempo Maximo Admisible (s)';
            app.TiemMaxAdmi = uieditfield(app.FourierTab, 'numeric');
            app.TiemMaxAdmi.ValueChangedFcn = createCallbackFcn(app, @TiemMaxAdmiValueChanged, true);
            app.TiemMaxAdmi.Editable = 'off';
            app.CriteriodeEstabilidadTextAreaLabel = uilabel(app.FourierTab);
            app.CriteriodeEstabilidadTextAreaLabel.Text = 'Criterio de Estabilidad';
            app.CriteriodeEstabilidadTextArea = uitextarea(app.FourierTab);
            app.CriteriodeEstabilidadTextArea.Editable = 'off';
            app.CriteriodeEstabilidadTextArea.Value = {'Para las condiciones establecidas, el intervalo de tiempo máximo admisible resulta ser el siguiente:'};
            app.SegnConvenienciaTextAreaLabel = uilabel(app.FourierTab);
            app.SegnConvenienciaTextAreaLabel.Text = 'Según Conveniencia';
            app.SegnConvenienciaTextArea = uitextarea(app.FourierTab);
            app.SegnConvenienciaTextArea.Value = {'Por favor seleccione una cantidad de iteraciones. La misma debe resultar en un intervalo de tiempo menor al mostrado anteriormente para que cumpla el Criterio de Estabilidad.'};
            app.IntervalodeTiempoporconvenienciasEditFieldLabel = uilabel(app.FourierTab);
            app.IntervalodeTiempoporconvenienciasEditFieldLabel.Text = 'Intervalo de Tiempo por conveniencia (s)';
            app.Tiemconveniencia = uieditfield(app.FourierTab, 'numeric');
            app.Tiemconveniencia.ValueChangedFcn = createCallbackFcn(app, @TiemconvenienciaValueChanged, true);
            app.Tiemconveniencia.Editable = 'off';
            app.NmerodeIteracionesEditFieldLabel = uilabel(app.FourierTab);
            app.NmerodeIteracionesEditFieldLabel.Text = 'Número de Iteraciones';
            app.NmerodeIteraciones = uieditfield(app.FourierTab, 'numeric');
            app.NmerodeIteraciones.ValueChangedFcn = createCallbackFcn(app, @NmerodeIteracionesValueChanged, true);
            app.CalcularButton = uibutton(app.FourierTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.CumpleLampLabel = uilabel(app.FourierTab);
            app.CumpleLampLabel.Text = '¿Cumple?';
            app.CumpleLamp = uilamp(app.FourierTab);
            app.TemperaturasTab = uitab(app.TabGroup);
            app.TemperaturasTab.Title = 'Temperaturas';
            app.MtodosNumricosparalaLabel_3 = uilabel(app.TemperaturasTab);
            app.MtodosNumricosparalaLabel_3.Text = 'Métodos Numéricos para la';
            app.ConduccindeCalorLabel_3 = uilabel(app.TemperaturasTab);
            app.ConduccindeCalorLabel_3.Text = 'Conducción de Calor';
            app.Descripcion3 = uilabel(app.TemperaturasTab);
            app.Descripcion3.Text = '(Para una Placa 1D, aislada en la parte inferior y expuesta a convección)';
            app.LastemperaturasenlosnodosdespuesdeltiempotranscurridosonLabel = uilabel(app.TemperaturasTab);
            app.LastemperaturasenlosnodosdespuesdeltiempotranscurridosonLabel.Text = 'Las temperaturas en los nodos despues del tiempo transcurrido son:';
            app.Tnodo0CEditFieldLabel = uilabel(app.TemperaturasTab);
            app.Tnodo0CEditFieldLabel.Text = 'Tnodo 0 (°C)';
            app.Tnodo0Muestra = uieditfield(app.TemperaturasTab, 'numeric');
            app.Tnodo0Muestra.ValueChangedFcn = createCallbackFcn(app, @Tnodo0MuestraValueChanged, true);
            app.Tnodo0Muestra.Editable = 'off';
            app.Tnodo1CEditFieldLabel = uilabel(app.TemperaturasTab);
            app.Tnodo1CEditFieldLabel.Text = 'Tnodo 1 (°C)';
            app.Tnodo1Muestra = uieditfield(app.TemperaturasTab, 'numeric');
            app.Tnodo1Muestra.ValueChangedFcn = createCallbackFcn(app, @Tnodo1MuestraValueChanged, true);
            app.Tnodo1Muestra.Editable = 'off';
            app.Tnodo2CEditFieldLabel = uilabel(app.TemperaturasTab);
            app.Tnodo2CEditFieldLabel.Text = 'Tnodo 2 (°C)';
            app.Tnodo2Muestra = uieditfield(app.TemperaturasTab, 'numeric');
            app.Tnodo2Muestra.ValueChangedFcn = createCallbackFcn(app, @Tnodo2MuestraValueChanged, true);
            app.Tnodo2Muestra.Editable = 'off';
            app.MaterialEditField_2Label = uilabel(app.TemperaturasTab);
            app.MaterialEditField_2Label.Text = 'Material';
            app.Material1 = uieditfield(app.TemperaturasTab, 'text');
            app.Material1.ValueChangedFcn = createCallbackFcn(app, @Material1ValueChanged, true);
            app.Material1.Editable = 'off';
            app.TiempoTranscurridominEditField_2Label = uilabel(app.TemperaturasTab);
            app.TiempoTranscurridominEditField_2Label.Text = 'Tiempo Transcurrido (min)';
            app.tiempoMuestra = uieditfield(app.TemperaturasTab, 'numeric');
            app.tiempoMuestra.ValueChangedFcn = createCallbackFcn(app, @tiempoMuestraValueChanged, true);
            app.tiempoMuestra.Editable = 'off';
            app.VerTemperaturasButton = uibutton(app.TemperaturasTab, 'push');
            app.VerTemperaturasButton.ButtonPushedFcn = createCallbackFcn(app, @VerTemperaturasButtonPushed, true);
            app.VerTemperaturasButton.Text = 'Ver Temperaturas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B50013_B56745_AppResina

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end